classdef yinyue < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        Tab                      matlab.ui.container.Tab
        Label_8                  matlab.ui.control.Label
        Label_9                  matlab.ui.control.Label
        Panel_2                  matlab.ui.container.Panel
        D0Spinner                matlab.ui.control.Spinner
        Label_6                  matlab.ui.control.Label
        exechlbtn                matlab.ui.control.Button
        PFBtnGroup               matlab.ui.container.ButtonGroup
        Label_5                  matlab.ui.control.Label
        Ideal_LPF                matlab.ui.control.RadioButton
        Gaussian_LPF             matlab.ui.control.RadioButton
        ButterWorth_LPF          matlab.ui.control.RadioButton
        hlfitertype              matlab.ui.container.ButtonGroup
        Button_5                 matlab.ui.control.RadioButton
        Button_4                 matlab.ui.control.RadioButton
        Panel                    matlab.ui.container.Panel
        execspacefilterbtn       matlab.ui.control.Button
        FilterTypesBtnGroup      matlab.ui.container.ButtonGroup
        Non_Linear               matlab.ui.control.RadioButton
        Label_3                  matlab.ui.control.Label
        Rec_Avrg                 matlab.ui.control.RadioButton
        Gaussian                 matlab.ui.control.RadioButton
        Gaussian_La              matlab.ui.control.RadioButton
        HistOriImage             matlab.ui.control.UIAxes
        HistImage                matlab.ui.control.UIAxes
        Tab_2                    matlab.ui.container.Tab
        Panel_4                  matlab.ui.container.Panel
        execotherprocbtn         matlab.ui.control.Button
        lightstepper             matlab.ui.control.Slider
        Label_10                 matlab.ui.control.Label
        Panel_3                  matlab.ui.container.Panel
        SmoothType               matlab.ui.control.DropDown
        DropDown_2Label          matlab.ui.control.Label
        SmoothfliterSize         matlab.ui.control.Spinner
        Label_11                 matlab.ui.control.Label
        execcolorbtn             matlab.ui.control.Button
        ColorimgProcessWayGroup  matlab.ui.container.ButtonGroup
        HSIRGBButton             matlab.ui.control.RadioButton
        RGBHSIButton             matlab.ui.control.RadioButton
        Button_10                matlab.ui.control.RadioButton
        Button_9                 matlab.ui.control.RadioButton
        Button_8                 matlab.ui.control.RadioButton
        Button_7                 matlab.ui.control.RadioButton
        Button_6                 matlab.ui.control.RadioButton
        DFTButton                matlab.ui.control.Button
        Tab_3                    matlab.ui.container.Tab
        Tab_4                    matlab.ui.container.Tab
        CompressCodeTable        matlab.ui.control.Table
        Label_7                  matlab.ui.control.Label
        DFTLabel                 matlab.ui.control.Label
        setnewnowimgbtn          matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        changeoriimagebtn        matlab.ui.control.Button
        NowImage                 matlab.ui.control.UIAxes
        OriImage                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        originfilesize = 0;
        ofilesize = 0;
        tabledata = [];
        userchoosefilename1 = "";
         
        userIm1 = [];
        preresultimage; % Description
    end
    
    methods (Access = private)
        
        function path = getfilename(app)
            [filename,path] = uigetfile({'*.jpg';'*.png';'*.tif';'*.bmp'});
            app.userchoosefilename1 = filename;
            if(path ~= 0)
                path= fullfile(path,filename);
            else
                path = 'lena.png';
            end
        end
        
        function init(app)
            setneworiimage(app,"lena.png");
            %app.OriginFileSize.Value = double(app.ofilesize)/1000 + "kB";                            
        end
        
        function [filesize,size] = getfileinfo(~,filename)
            info = imfinfo(filename);
            filesize = info.FileSize;
            size = info.Width*info.Height*info.BitDepth;
        end
        
        function applyendimage(app,g)
            cla(app.HistImage,'reset');
            histogram(app.HistImage,g);
            imshow(g,'Parent',app.NowImage);
            app.preresultimage=g;
        end
        
        function g = myimfliter(~,f,a)
            switch(a)
                case "高斯-拉普拉斯"
                    w = fspecial('log');
                    g = imfilter(f,w); 
                case "高斯"
                    w = fspecial('gaussian',[3 3],0.5);
                    g = imfilter(f,w); 
                case "矩形平均"
                    w = fspecial('average',[3 3]);
                    g = imfilter(f,w); 
                case "中位数滤波器"
                    g = medfilt2(f);
            end
        end  
        
        function I = GetNowGrayImage(app)
            I=im2double(rgb2gray(app.userIm1));
        end
        
        function results = GetD0SettingValue(app)
            results=app.D0Spinner.Value;
        end
        
        function setneworiimage(app,filename)
            app.userchoosefilename1=filename;
            app.userIm1 = imread(filename);
            imshow(app.userIm1,'Parent',app.OriImage);
            colorhistogram(app.HistOriImage,app.userIm1);
            [app.ofilesize,app.originfilesize] = getfileinfo(app,filename);
        end
        
        function setneworiimageforimage(app,image)
            app.userchoosefilename1="";
            app.userIm1 = image;
            imshow(app.userIm1,'Parent',app.OriImage);
            colorhistogram(app.HistOriImage,app.userIm1);
        end
        
        function d=grayhistogram(~,I)%构造histogram函数
            %此函数用于均衡化
            J=I;
            [m,n]=size(I);      %确定矩阵大小
            area=m*n;
            a=zeros(1,256);     %产生1*256的零矩阵a,用来存放原始图像各个灰度值的个数
            b=zeros(1,256);
            for i=1:m           %记录各个灰度值的个数
                for j=1:n
                    d=I(i,j)+1;   %获取(i,j)位置的灰度值(注意：灰度值为0-255，对应矩阵的1-256）
                    a(1,d)=a(1,d)+1;    %矩阵a上对应灰度值的计数+1
                end
            end
            for i=1:256         %均衡化
                sum=0;
                for j=1:i
                    sum=sum+a(1,j);
                end
                b(1,i)=sum*255/area;
            end
            for i=1:m           %用均衡化后的数据代替原位置的数据
                for j=1:n
                    d=J(i,j)+1;
                    J(i,j)=b(1,d);
                end
            end
            d = J;
        end
        
        function out = coloraverage(app,im)
            if(ifcolorimage(im))
            %此函数用于彩图均衡化
            I1=im(:,:,1);%提取红色分量
            I2=im(:,:,2);%提取绿色分量
            I3=im(:,:,3);%提取蓝色分量
            I1=grayhistogram(app,I1);   %构造的函数
            I2=grayhistogram(app,I2);
            I3=grayhistogram(app,I3);
            out=cat(3,I1,I2,I3);  %cat用于恢复成彩图
            else
                out=grayhistogram(app,im);
            end
        end
        
        function out = BrightRGB(~,im,strength)
            %此函数用于增强图片亮度
            %im为输入图片，strength为增强的倍率
            %增强倍率默认为1（也就是不增强
            if(ifcolorimage(im))
                R = im(:,:,1)*strength;
                G = im(:,:,2)*strength;
                B = im(:,:,3)*strength;
                out = cat(3,R,G,B);
            else
                out=im(:,:)*strength;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            init(app);
        end

        % Button pushed function: changeoriimagebtn
        function changeoriimagebtnPushed(app, event)
            path= getfilename(app);
            if(path ~= 0)
                app.userIm1 = imread(path);
            end
            imshow(app.userIm1,'Parent',app.OriImage);
        end

        % Button pushed function: execspacefilterbtn
        function execspacefilterbtnButtonPushed(app, event)
            flitertypeText = app.FilterTypesBtnGroup.SelectedObject.Text;
            g = myimfliter(app,GetNowGrayImage(app),flitertypeText);
            applyendimage(app,g);
        end

        % Button pushed function: exechlbtn
        function exechlbtnButtonPushed(app, event)
            flitertypeText=app.hlfitertype.SelectedObject.Text;
            fliterwayText=app.PFBtnGroup.SelectedObject.Text;
            D0=GetD0SettingValue(app);
            img=GetNowGrayImage(app);
            Ig=[];
            ishigh=flitertypeText=="高通滤波器";
            switch(fliterwayText)
                case "理想"
                    if(ishigh)
                        Ig=Ideal_high_pass_filter(img,D0);
                    else
                        Ig=Ideal_low_pass_filter(img,D0);
                    end
                case "巴特沃斯"
                    if(ishigh)
                        Ig=Butterworth_high_pass_filter(img,D0);
                    else
                        Ig=Butterworth_low_pass_filter(img,D0);
                    end
                case "高斯"
                    if(ishigh)
                        Ig=Gaussian_high_pass_filter(img,D0);
                    else
                        Ig=Gaussian_low_pass_filter(img,D0);
                    end
            end
            applyendimage(app,Ig);
        end

        % Button pushed function: setnewnowimgbtn
        function setnewnowimgbtnButtonPushed(app, event)
            setneworiimageforimage(app,app.preresultimage);
        end

        % Button pushed function: DFTButton
        function DFTButtonPushed(app, event)
            img=DFT(im2double(app.userIm1));
            applyendimage(app,img);
            imshow(log(img+1),'Parent',app.NowImage);
        end

        % Button pushed function: execcolorbtn
        function execcolorbtnButtonPushed(app, event)
            currentWay = app.ColorimgProcessWayGroup.SelectedObject.Text;
            I=app.userIm1;
            switch(currentWay)
                case "均衡化"
                    out1 = coloraverage(app,I);
                case "边缘检测"
                    out1 = rgbEdgeDetection(I); 
                case "平滑"
                    out1 = Color_image_smoothing(I,app.SmoothType.Value,app.SmoothfliterSize.Value);
                case "灰度图转化为伪彩色图"
                    if(ifcolorimage(I)==false)
                        out1 =Gray_level_to_color_transformation_processing(I);
                    else
                        out1=I;
                        msgbox("已经是彩图");
                    end
                case "彩色图转灰度图"
                    out1 =im2gray(I);
                case "RGB->HSI"
                    if(ifcolorimage(I))
                        out1=rgb2hsi(I);
                    else
                        out1=I;
                        msgbox("不能对灰度图执行该操作");
                    end
                case "HSI->RGB"
                    if(ifcolorimage(I))
                        out1=hsi2rgb(I);
                    else
                        out1=I;
                        msgbox("不能对灰度图执行该操作");
                    end
            end
            applyendimage(app,out1);
        end

        % Button pushed function: execotherprocbtn
        function execotherprocbtnButtonPushed(app, event)
            applyendimage(app,BrightRGB(app,app.userIm1,1+app.lightstepper.Value/100.0));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriImage = uiaxes(app.UIFigure);
            app.NowImage = uiaxes(app.UIFigure);
            app.changeoriimagebtn = uibutton(app.UIFigure, 'push');
            app.changeoriimagebtn.ButtonPushedFcn = createCallbackFcn(app, @changeoriimagebtnPushed, true);
            app.changeoriimagebtn.Text = {'更换待处理图像'; ''};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '待处理图像';
            app.setnewnowimgbtn = uibutton(app.UIFigure, 'push');
            app.setnewnowimgbtn.ButtonPushedFcn = createCallbackFcn(app, @setnewnowimgbtnButtonPushed, true);
            app.setnewnowimgbtn.Text = {'设置为新的待处理图像'; ''};
            app.DFTLabel = uilabel(app.UIFigure);
            app.DFTLabel.Text = '处理后图像/DFT频谱';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '灰度图像处理';
            app.HistImage = uiaxes(app.Tab);
            xlabel(app.HistImage, 'X')
            ylabel(app.HistImage, 'Y')
            zlabel(app.HistImage, 'Z')
            app.HistOriImage = uiaxes(app.Tab);
            xlabel(app.HistOriImage, 'X')
            ylabel(app.HistOriImage, 'Y')
            zlabel(app.HistOriImage, 'Z')
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = '空间滤波';
            app.FilterTypesBtnGroup = uibuttongroup(app.Panel);
            app.Gaussian_La = uiradiobutton(app.FilterTypesBtnGroup);
            app.Gaussian_La.Text = '高斯-拉普拉斯';
            app.Gaussian_La.Value = true;
            app.Gaussian = uiradiobutton(app.FilterTypesBtnGroup);
            app.Gaussian.Text = '高斯';
            app.Rec_Avrg = uiradiobutton(app.FilterTypesBtnGroup);
            app.Rec_Avrg.Text = '矩形平均';
            app.Label_3 = uilabel(app.FilterTypesBtnGroup);
            app.Label_3.Text = '滤波器种类';
            app.Non_Linear = uiradiobutton(app.FilterTypesBtnGroup);
            app.Non_Linear.Text = '中位数滤波器';
            app.execspacefilterbtn = uibutton(app.Panel, 'push');
            app.execspacefilterbtn.ButtonPushedFcn = createCallbackFcn(app, @execspacefilterbtnButtonPushed, true);
            app.execspacefilterbtn.Text = '执行';
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = '低通/高通滤波器';
            app.hlfitertype = uibuttongroup(app.Panel_2);
            app.hlfitertype.Title = '滤波器种类';
            app.Button_4 = uiradiobutton(app.hlfitertype);
            app.Button_4.Text = '低通滤波器';
            app.Button_4.Value = true;
            app.Button_5 = uiradiobutton(app.hlfitertype);
            app.Button_5.Text = '高通滤波器';
            app.PFBtnGroup = uibuttongroup(app.Panel_2);
            app.ButterWorth_LPF = uiradiobutton(app.PFBtnGroup);
            app.ButterWorth_LPF.Text = '巴特沃斯';
            app.ButterWorth_LPF.Value = true;
            app.Gaussian_LPF = uiradiobutton(app.PFBtnGroup);
            app.Gaussian_LPF.Text = '高斯';
            app.Ideal_LPF = uiradiobutton(app.PFBtnGroup);
            app.Ideal_LPF.Text = '理想';
            app.Label_5 = uilabel(app.PFBtnGroup);
            app.Label_5.Text = '滤波方式';
            app.exechlbtn = uibutton(app.Panel_2, 'push');
            app.exechlbtn.ButtonPushedFcn = createCallbackFcn(app, @exechlbtnButtonPushed, true);
            app.exechlbtn.Text = '执行';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = '截止频率半径';
            app.D0Spinner = uispinner(app.Panel_2);
            app.D0Spinner.Value = 50;
            app.Label_9 = uilabel(app.Tab);
            app.Label_9.Text = '处理后直方图';
            app.Label_8 = uilabel(app.Tab);
            app.Label_8.Text = {'待处理直方图'; ''};
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '彩色图像处理';
            app.DFTButton = uibutton(app.Tab_2, 'push');
            app.DFTButton.ButtonPushedFcn = createCallbackFcn(app, @DFTButtonPushed, true);
            app.DFTButton.Text = 'DFT';
            app.Panel_3 = uipanel(app.Tab_2);
            app.Panel_3.Title = '彩图处理';
            app.ColorimgProcessWayGroup = uibuttongroup(app.Panel_3);
            app.ColorimgProcessWayGroup.Title = '处理方式';
            app.Button_6 = uiradiobutton(app.ColorimgProcessWayGroup);
            app.Button_6.Text = '均衡化';
            app.Button_6.Value = true;
            app.Button_7 = uiradiobutton(app.ColorimgProcessWayGroup);
            app.Button_7.Text = '边缘检测';
            app.Button_8 = uiradiobutton(app.ColorimgProcessWayGroup);
            app.Button_8.Text = '平滑';
            app.Button_9 = uiradiobutton(app.ColorimgProcessWayGroup);
            app.Button_9.Text = '灰度图转化为伪彩色图';
            app.Button_10 = uiradiobutton(app.ColorimgProcessWayGroup);
            app.Button_10.Text = '彩色图转灰度图';
            app.RGBHSIButton = uiradiobutton(app.ColorimgProcessWayGroup);
            app.RGBHSIButton.Text = 'RGB->HSI';
            app.HSIRGBButton = uiradiobutton(app.ColorimgProcessWayGroup);
            app.HSIRGBButton.Text = 'HSI->RGB';
            app.execcolorbtn = uibutton(app.Panel_3, 'push');
            app.execcolorbtn.ButtonPushedFcn = createCallbackFcn(app, @execcolorbtnButtonPushed, true);
            app.execcolorbtn.Text = '执行';
            app.Label_11 = uilabel(app.Panel_3);
            app.Label_11.Text = '平滑滤波器大小';
            app.SmoothfliterSize = uispinner(app.Panel_3);
            app.SmoothfliterSize.Limits = [2 Inf];
            app.SmoothfliterSize.Value = 2;
            app.DropDown_2Label = uilabel(app.Panel_3);
            app.DropDown_2Label.Text = '平滑类型';
            app.SmoothType = uidropdown(app.Panel_3);
            app.SmoothType.Items = {'rgb', 'hsi'};
            app.SmoothType.Value = 'rgb';
            app.Panel_4 = uipanel(app.Tab_2);
            app.Panel_4.Title = '其它调整';
            app.Label_10 = uilabel(app.Panel_4);
            app.Label_10.Text = '亮度';
            app.lightstepper = uislider(app.Panel_4);
            app.lightstepper.Limits = [-100 100];
            app.execotherprocbtn = uibutton(app.Panel_4, 'push');
            app.execotherprocbtn.ButtonPushedFcn = createCallbackFcn(app, @execotherprocbtnButtonPushed, true);
            app.execotherprocbtn.Text = '执行';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '图像复原与重建';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '图像压缩';
            app.Label_7 = uilabel(app.Tab_4);
            app.Label_7.Text = '压缩编码';
            app.CompressCodeTable = uitable(app.Tab_4);
            app.CompressCodeTable.ColumnName = {'哈夫曼编码'; '预测编码'};
            app.CompressCodeTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = yinyue

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end